classdef draw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResultsPanel              matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        NomedaredeEditField       matlab.ui.control.EditField
        NomedaredeEditFieldLabel  matlab.ui.control.Label
        SimularButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
          grayImage = ones(224,224);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
             hFH = drawfreehand(app.UIAxes);
            
            % Get the xy coordinates of where they drew.
            xy = hFH.Position;
            % get rid of imfreehand remnant.
            delete(hFH);
            hold(app.UIAxes, 'on');
            xCoordinates = xy(:, 1);
            yCoordinates = xy(:, 2);
            plot(app.UIAxes, xCoordinates, yCoordinates, 'ro-', 'LineWidth', 2);
            
            promptMessage = sprintf('Do you want to use this image?');
            titleBarCaption = 'Continue?';
            button = questdlg(promptMessage, titleBarCaption, 'OK', 'Cancel', 'OK');
            if strcmpi(button, 'Cancel')
                cla(app.UIAxes);
                hold(app.UIAxes, 'off');
                title(app.UIAxes, "Left click and drag to draw");
                return;
            end
            title(app.UIAxes, "");
            
            
            for k = 1 : length(xCoordinates)
                row = int32(yCoordinates(k));
                column = int32(xCoordinates(k));
                disp(column);
                disp(row);
                app.grayImage(row, column) = 0;
            end
            app.grayImage = flipud(app.grayImage);
            %imshow(app.grayImage, 'Parent', app.UIAxes);
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
             image = app.grayImage;
             textLabel = sprintf("A rede indentificou a imagem como: %s",loadNetworkSingleImage(app.NomedaredeEditField.Value,"null",image));
             app.Label.Text = textLabel;

        end

        % Callback function
        function ResetDrawingPushed(app, event)
            % Create ResetDrawing
%             app.ResetDrawing = uibutton(app.ManualDrawPanel, 'push');
%             app.ResetDrawing.ButtonPushedFcn = createCallbackFcn(app, @ResetDrawingPushed, true);
%             app.ResetDrawing.BackgroundColor = [0.302 0.7451 0.9333];
%             app.ResetDrawing.FontSize = 9;
%             app.ResetDrawing.FontWeight = 'bold';
%             app.ResetDrawing.Position = [909 590 40 15];
%             app.ResetDrawing.Text = 'Clean';
              clf(image,'reset');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Left click and drag to draw')
            app.UIAxes.XLim = [0 224];
            app.UIAxes.YLim = [0 224];
            app.UIAxes.ZLim = [0 224];
            app.UIAxes.XTick = 0.224;
            app.UIAxes.YTick = 0.224;
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.NomedaredeEditFieldLabel = uilabel(app.UIFigure);
            app.NomedaredeEditFieldLabel.Text = 'Nome da rede';
            app.NomedaredeEditField = uieditfield(app.UIFigure, 'text');
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.Label = uilabel(app.ResultsPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = draw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end